classdef app_journal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        generate_meta_dataButton   matlab.ui.control.Button
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        ReadtheJournalLabel        matlab.ui.control.Label
        SteptocreateajournalLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        dfinirlesphasesdacquisitionetdactivationButton  matlab.ui.control.StateButton
        save_parametersButton      matlab.ui.control.Button
        PathwayButton              matlab.ui.control.Button
        Help_journalandbugreporterButton  matlab.ui.control.Button
        ReadButton                 matlab.ui.control.Button
    end

properties (Access = private)
    %Objet contenant les timers ;
     t1 = 0;
     t2 = 0;
     tfocu = 0;
     stock = 0;

    %Parametre du journal ;
     w_acqui=0;
     w_acti = 0;
     periode_acq;
     periode_act;
     t_act ;
     t_acq;
     nb_acquisition ;
     nb_activation ;
     period;
     duration;
     tot_temps;
    %Parametre pour l'enregistrement des images
     path;
     Name_image;

    %si il y a meme periode ou pas 
    same_periode ;
    same_duration ;
     
end
    
    
methods (Access = private)
        
function acquisition (app,obj,event)
    currentFolder = pwd;
    assembly = NET.addAssembly([currentFolder,'\Interop.MMAppLib.dll']); 
    core = MMAppLib.UserCallClass();
   

    a = comptage_app(1);

persistent i;
persistent j;
persistent c_acq;
persistent c_act;
persistent b;
persistent cp_act;
persistent cp_acq;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Si meme periode et meme durée :
if app.same_duration == 1 && app.same_periode == 1 
     
    disp(a)
    
    if isempty(i)
    i = 0;
    j = app.duration;
    end

    %pour la periode 
    if mod(a,app.period) ==0
        
        %pour la durée
        if i<a && a<j
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');
            disp('acquisition');
        elseif a==j
            i = i + app.duration*2;
            disp('changement de phase');
        elseif j<a && a<i
core.SetMMVariable('Device.Illumination.Setting',app.w_acti);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite (I1,'test.tif','WriteMode','append');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');
disp('activation');
        elseif a==i
                j = j + app.duration*2;
                disp('changement de phase');
        end
    end
end
 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if app.same_duration == 0 && app.same_periode == 1 
    disp(a)
if isempty(i)
    i = 0;
    j = app.t_acq(1);
    c_act = 1;
    c_acq = 1;
end

    %pour la periode 
    if mod(a,app.period) ==0
        if i<a && a<j
            disp('acquisition');
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');           
        elseif a==j
            i = i + app.t_acq(c_acq) + app.t_act(c_acq);
            c_acq = c_acq + 1;
            disp(i)
            disp(j)
            disp(c_acq)
            disp('changement de phase');
        elseif j<a && a<i
core.SetMMVariable('Device.Illumination.Setting',app.w_acti);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');
            disp('activation');
        elseif a==i
            j = j + app.t_acq(c_act) + app.t_act(c_act);
            c_act = c_act + 1;
            disp(i)
            disp(j)
            disp(c_act)
            disp('changement de phase');
        end
    end
end


if app.same_duration == 1 && app.same_periode == 0

    disp(a)
    
    if isempty(i)
    i = 0;
    j = app.duration;
    b = app.periode_acq(1);
    c_act = 1;
    c_acq = 2;
    end

    %pour la periode 
    if mod(a,b) ==0
        
        %pour la durée
        if i<a && a<j
            disp('acquisition');
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');
        elseif a==j
            i = i + app.duration*2;
            disp('changement de phase');
            b = app.periode_act(c_act);
            c_act = c_act +1;
        elseif j<a && a<i
core.SetMMVariable('Device.Illumination.Setting',app.w_acti);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite (I1,'test.tif','WriteMode','append');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');
            disp('activation');
        elseif a==i
                j = j + app.duration*2;
                b = app.periode_acq(c_acq);
                c_acq = c_acq +1;
                disp('changement de phase');
        end
    end


end


if app.same_duration == 0 && app.same_periode == 0
    disp(a)
    if isempty(i)
    i = 0;
    j = app.t_acq(1);
    c_act = 1;
    c_acq = 1;

    b = app.periode_acq(1);
    cp_act =1;
    cp_acq = 2;
    end

    if mod(a,b) ==0
        if i<a && a<j
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');
disp('acquisition');
        elseif a==j
            i = i + app.t_acq(c_acq) + app.t_act(c_acq);
            c_acq = c_acq + 1;
            
            disp('changement de phase');
            b = app.periode_act(cp_act);
            cp_act = cp_act +1;
        elseif j<a && a<i
disp('activation');
core.SetMMVariable('Device.Illumination.Setting',app.w_acti);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
core.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
I1 = imread('C:\TEMP\temp.tif');
imwrite(I1,strcat(app.path,app.Name_image.name),'WriteMode','append');
        elseif a==i
            j = j + app.t_acq(c_act) + app.t_act(c_act);
            c_act = c_act + 1;
               
            b = app.periode_acq(cp_acq);
            cp_acq = cp_acq +1;
            disp('changement de phase');
        end
    end
end
end     
function Leon_nettoyeur (app,obj,event)
            stop(obj);
            disp('Nettoyé');
            clear comptage_app
            clear i
            clear j  
        end
        %L'algorithm calcul un score de focus de l'image, on compare le
        %score de focus avec l'image initial. Si il y a eu un drift 
        %l'auto-focus se declenche. Le score de focus obtenu aprés le
        %déclanchement sert de nouveau référentiel pour les prochains
        %test. Source : Analysis of focus measure operators for shape-from-focus
        %By Pertruz et al. il a un matlab qui fournis l'autofocus. (voir
        %l'article)
function auto_focus(app,obj,event)
        persistent compte;
        persistent fm;
        persistent j;
        assembly = NET.addAssembly([currentFolder,'\Interop.MMAppLib.dll']); 
        Obj = MMAppLib.UserCallClass();
        core.SetMMVariable('Device.Illumination.Setting',app.w_acqui);
        %initialisation
if isempty(fm)
fm =[];

position_autofocus = zeros(17);
valeur_contrast = zeros();
pos = [-0.04 -0.03 -0.02 -0.01 0 0.01 0.2 0.3 0.4];
            %pos = [-8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8]
            f = 0;
            a = 0;
            Obj.RunJournal('C:\MM\app\mmproc\journals\stoplive.JNL');
          
                    for i=1:9
                        a = pos(i);
                        Obj.SetMMVariable('Device.Focus.CurPos',+a);
                        [~,~,position_autofocus(i)] = Obj.GetMMVariable('Device.Focus.CurPos',0); 
                        Obj.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
                        I = imread('C:\TEMP\temp.tif');
                        valeur_contrast(i) = fmeasure(I,'BREN');
                        disp(valeur_contrast)
                    end
f = max(valeur_contrast);
                    a = find(f==valeur_contrast);
                    Obj.SetMMVariable('Device.Focus.CurPos',position_autofocus(a));
Obj.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
img = imread('C:\TEMP\temp.tif');
fm(1) = fmeasure(img,'BREN');
compte = compte +1;
disp(fm);
j = 1;
else 
j= j+1;    
Obj.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
img = imread('C:\TEMP\temp.tif');
fm(j) = fmeasure(img,'BREN');
disp(fm(j));
disp(j);
end 

 %A partir de quelle valeur on considérer l'image défocus ? 
 
 %Si le score de focus est pas bien, lancement de l'auto-focus
 
 if fm(1)-100>fm(j) ||  fm(1)+100<fm(j) 
     position_autofocus = zeros(17);
            valeur_contrast = zeros();
            pos = [-0.04 -0.03 -0.02 -0.01 0 0.01 0.2 0.3 0.4];
            
            f = 0;
            a = 0;
            Obj.RunJournal('C:\MM\app\mmproc\journals\stoplive.JNL');
          
                    for i=1:9
                        a = pos(i);
                        Obj.SetMMVariable('Device.Focus.CurPos',+a);
                        [~,~,position_autofocus(i)] = Obj.GetMMVariable('Device.Focus.CurPos',0); 
                        Obj.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
                        I = imread('C:\TEMP\temp.tif');
                        valeur_contrast(i) = fmeasure(I,'BREN');
                        disp(valeur_contrast)
                    end
                    compte = compte +1;
                    f = max(valeur_contrast);
                    a = find(f==valeur_contrast);
                    Obj.SetMMVariable('Device.Focus.CurPos',position_autofocus(a));
Obj.RunJournal('C:\MM\app\mmproc\journals\s.JNL');
img = imread('C:\TEMP\temp.tif');
fm(1) = fmeasure(img,'BREN');
 end 
 disp(compte);
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadButton
        function read(app, event)
        start(app.t1)
        end

        % Button pushed function: Help_journalandbugreporterButton
        function help(app, event)
sprintf(['Wave length avalaible : CSU405, CSU488, CSU561, CSU642, CSUTRANS. (to create a new one go on metamorph and add the new one in the following list. MERCI\n'...
    'The period for a journal to do their function is 2s, if you want to acquire 1 min 30 photos will be taken. \n' ...
    'The auto-focus is implemented automatically in the app. If you dont want to use it go in the function read and delete the poucent and add a pourcent on the other line.\n'...
    'If you want to suggest or report smth just open the app in matlab, go in the code view, go in the function help, write our things on this line : \n'...
    'Be careful if you want to change the code, if you break it, you will break my little heart too  :( .'])
%If you wan to suggest an new function of the app or report a bug please
%write in the sprintf function below;
            %sprintf ('');
        end

        % Button pushed function: PathwayButton
        function patway(app, event)
           sprintf('Creation of the pathway where the image will be save (ex: "D:\detrending\export")');
           app.path = input('Write the patway where the image will be saved');
           app.Name_image = input('Write the image name, an increment will be done when saving, ex: image_name(i)');
        end

        % Button pushed function: save_parametersButton
        function save(app, event)
           %Initialisation timer en fonction de l'écriture du journal : 
         if app.same_periode == 1 && app.same_duration==1
         app.t1 = timer; 
         app.tfocu = timer;
         app.t_acq = app.t_acq(app.t_acq~=1);
         app.t_act =  app.t_act(app.t_act~=1);
         app.periode_acq = app.periode_acq(app.periode_acq~=1);
         app.periode_act = app.periode_act( app.periode_act~=1);
         app.tot_temps = (app.nb_acquisition + app.nb_activation) * app.duration; 
         app.t1.StopFcn = @app.Leon_nettoyeur;
         app.t1.Period = 1;
         app.t1.TasksToExecute = app.tot_temps;
         app.t1.ExecutionMode = 'fixedSpacing';
         app.t1.BusyMode = 'queue';
         app.t1.TimerFcn = @app.acquisition;
         elseif app.same_periode == 1 && app.same_duration==0
         app.t1 = timer;
         app.t_acq = app.t_acq(app.t_acq~=1);
         app.t_act =  app.t_act(app.t_act~=1);
         app.periode_acq = app.periode_acq(app.periode_acq~=1);
         app.periode_act = app.periode_act( app.periode_act~=1);
         app.tot_temps = (sum(app.t_acq) + sum((app.t_act)));   
         app.t1.StopFcn = @app.Leon_nettoyeur;
         app.t1.Period = 1;
         app.t1.TasksToExecute = app.tot_temps;
         app.t1.ExecutionMode = 'fixedSpacing';
         app.t1.BusyMode = 'queue';
         app.t1.TimerFcn = @app.acquisition;
         elseif app.same_periode == 0 && app.same_duration==1
         app.t1 = timer; 
         app.tfocu = timer;
         app.t_acq = app.t_acq(app.t_acq~=1);
         app.t_act =  app.t_act(app.t_act~=1);
         app.periode_acq = app.periode_acq(app.periode_acq~=1);
         app.periode_act = app.periode_act( app.periode_act~=1);
         app.tot_temps = (app.nb_acquisition + app.nb_activation) * app.duration;
         app.t1.StopFcn = @app.Leon_nettoyeur;
         app.t1.Period = 1;
         app.t1.TasksToExecute = app.tot_temps;
         app.t1.ExecutionMode = 'fixedSpacing';
         app.t1.BusyMode = 'queue';
         app.t1.TimerFcn = @app.acquisition;
         elseif app.same_periode == 0 && app.same_duration==0
         app.t1 = timer; 
         app.tfocu = timer;
         app.t_acq = app.t_acq(app.t_acq~=1);
         app.t_act =  app.t_act(app.t_act~=1);
         app.periode_acq = app.periode_acq(app.periode_acq~=1);
         app.periode_act = app.periode_act( app.periode_act~=1);
         app.tot_temps = (sum(app.t_acq) + sum((app.t_act)));
         app.t1.StopFcn = @app.Leon_nettoyeur;
         app.t1.Period = 1;
         app.t1.TasksToExecute = app.tot_temps;
         app.t1.ExecutionMode = 'fixedSpacing';
         app.t1.BusyMode = 'queue';
         app.t1.TimerFcn = @app.acquisition;
         end
        end

        % Value changed function: 
        % dfinirlesphasesdacquisitionetdactivationButton
        function acq_act(app, event)
      app.w_acqui = input('Wich wavelenght for the acquisition ?');
      app.w_acti = input('Wich wavelenght for the actication ?');
      sprintf(['The activation phase use the wavelenght %s \n ' ...
                'The acquisition phase use the wavelenght %s.'],app.w_acti,app.w_acqui)
        
        app.nb_acquisition = input('How much acquisition phases');
        app.nb_activation = input('How much activation phases ?');
        app.t_act = [app.nb_acquisition];
        app.t_acq = [app.nb_activation];

         sprintf(['Informations about the numbers of phases: \n ' ...
                'Acquisition phase(s) ; number of acquisition phase(s) ; %d \n' ...
                'Activation phase(s) ; number of activation phase(s) ; %d'],app.nb_acquisition,app.nb_activation)

%definition de la durée des phases
txt = input("Do you want the same duration for each phase? Y/N type 'Y' or 'N':");
    if txt == 'Y'
    app.same_duration = 1;
    app.duration = input('What is the duration of the phases ? :');
    sprintf(['Informations about the period: \n ' ...
               'The period of the phases is %d \n '],app.duration)  
    elseif txt == 'N'
    app.same_duration = 0;
    if app.nb_acquisition>app.nb_activation
        for i=1:app.nb_activation
             app.t_acq(i) = input(strcat('Wich duration for the acquisition phase nb_', num2str(i)));
             app.t_act(i) = input(strcat('Wich duration for the activation phase nb_', num2str(i)));
        end
        for i=(app.nb_activation+1):app.nb_acquisition
            app.t_act(i) = 1;
            app.t_acq(i) = input(strcat('Wich duration for the acquisition phase nb_', num2str(i)));
        end
    end
    
    if app.nb_acquisition<app.nb_activation 
        for i=1:app.nb_acquisition
             app.t_acq(i) = input(strcat('Wich duration for the acquisition phase nb_', num2str(i)));
             app.t_act(i) = input(strcat('Wich duration for the activation phase nb_', num2str(i)));
        end
        for i =(app.nb_acquisition + 1):app.nb_activation
            app.t_acq(i) = 1;
            app.t_act(i) = input(strcat('Wich duration for the acquisition phase nb_', num2str(i)));
        end
    end

    %definition des temps de chaque phase
         if app.nb_activation<app.nb_acquisition
          a = app.nb_acquisition;
          d = app.nb_activation;
          else
          a = app.nb_activation;
          d =app.nb_acquisition;
         end

         for i=1:a
             b = app.t_act(i);
             c = app.t_acq(i);
             if i <= d
             sprintf(['Informations about phases duration: \n ' ...
               'duration phase(s) ; duration of the acquisition phase(s) ; %d \n ' ...
               'duration phase(s) ; duration of the activation phase(s) ; %d \n '],b,c)
             elseif i > app.nb_acquisition
            sprintf(['Informations about phases duration: \n ' ...
               'duration phase(s) ; duration of the activation phase(s) ; %d \n '],b)
             elseif i > app.nb_activation
                  sprintf(['Informations about phases duration: \n ' ...
               'duration phase(s) ; duration of the acquisition phase(s) ; %d \n '],c)
             end
         end
     end

%Definitino de la period et du task to execute 

txt = input("Do you want the same period for each phase? Y/N type 'Y' or 'N':");
if txt == 'Y'
    app.same_periode = 1;
    app.period = input('What is the period of the phases ? :');
    sprintf(['Informations about the period: \n ' ...
               'The period of the phases is %d \n '],app.period)
elseif txt == 'N'
    app.same_periode = 0;

    if app.nb_acquisition>app.nb_activation
        for i=1:app.nb_activation
             app.periode_acq(i) = input(strcat('Wich period for the acquisition phase nb_', num2str(i)));
             app.periode_act(i) = input(strcat('Wich period for the activation phase nb_', num2str(i)));
        end
        for i=(app.nb_activation+1):app.nb_acquisition
             app.periode_act(i) = 1;
             app.periode_acq(i) = input(strcat('Wich period for the acquisition phase nb_', num2str(i)));
        end
    end
    
    if app.nb_acquisition<app.nb_activation 
        for i=1:app.nb_acquisition
             app.periode_acq(i) = input(strcat('Wich period for the acquisition phase nb_', num2str(i)));
             app.periode_act(i) = input(strcat('Wich period for the activation phase nb_', num2str(i)));
        end
        for i =(app.nb_acquisition + 1):app.nb_activation
          app.periode_acq(i) = 1;
          app.periode_act(i) = input(strcat('Wich period for the activation phase nb_', num2str(i)));
        end
        
    end

   if app.nb_activation<app.nb_acquisition
          a = app.nb_acquisition;
          d = app.nb_activation;
          else
          a = app.nb_activation;
          d =app.nb_acquisition;
   end
  for i=1:a
      b = app.periode_acq(i);
      c = app.periode_act(i);
             if i <= d
            sprintf(strcat(['Informations about the periods: \n ' ...
               'The period of acquisition nb',num2str(i), 'of the phases is %d \n '...
               'The period of activation nb',num2str(i), 'of the phases is %d \n ']),b,c)
             elseif i > app.nb_acquisition
           sprintf(strcat('The period of activation nb',num2str(i), 'of the phases is %d \n '),c)
             elseif i > app.nb_activation
             sprintf(strcat('The period of acquisition nb',num2str(i), 'of the phases is %d \n '),b)
             end
  end
  
end

        end

        % Button pushed function: generate_meta_dataButton
        function meta_data(app, event)
%fid = fopen(strcat(app.path,'meta_data.txt'), 'wt' );
fid = fopen('meta_data.txt', 'wt');

%Données demandées 
a1 = 'acquisition_wavelenght : ';
a2 = app.w_acqui;
a3 = 'activation_wavelenght : ';
a4 = app.w_acti;
b1 = 'nb_de_phase_acquisition : ';
b2 = 'nb_nb_de_phase_activation :';
b3 = app.nb_acquisition;
b4 = app.nb_activation;
c1 = app.period;
c2 = 'Period';

%Données microscope

fprintf(fid,'%s %s \n %s %s \n %s %f \n %s %f \n %s %f',a1,a2,a3,a4,b1,b3,b2,b4,c2,c1);

fclose(fid);

%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Ne pas oublier de la faire pour plusieurs trucs
%%%%%%%%%%%%%%%%%%%%%%%%%%%
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @read, true);
            app.ReadButton.Text = 'Read';
            app.Help_journalandbugreporterButton = uibutton(app.UIFigure, 'push');
            app.Help_journalandbugreporterButton.ButtonPushedFcn = createCallbackFcn(app, @help, true);
            app.Help_journalandbugreporterButton.Text = 'Help_journal and bug reporter';
            app.PathwayButton = uibutton(app.UIFigure, 'push');
            app.PathwayButton.ButtonPushedFcn = createCallbackFcn(app, @patway, true);
            app.PathwayButton.Text = 'Pathway';
            app.save_parametersButton = uibutton(app.UIFigure, 'push');
            app.save_parametersButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.save_parametersButton.Text = 'save_parameters';
            app.dfinirlesphasesdacquisitionetdactivationButton = uibutton(app.UIFigure, 'state');
            app.dfinirlesphasesdacquisitionetdactivationButton.ValueChangedFcn = createCallbackFcn(app, @acq_act, true);
            app.dfinirlesphasesdacquisitionetdactivationButton.Text = 'définir les phases d''acquisition et d''activation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'load.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SteptocreateajournalLabel = uilabel(app.UIFigure);
            app.SteptocreateajournalLabel.Text = 'Step to create a journal';
            app.ReadtheJournalLabel = uilabel(app.UIFigure);
            app.ReadtheJournalLabel.Text = 'Read the Journal ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.generate_meta_dataButton = uibutton(app.UIFigure, 'push');
            app.generate_meta_dataButton.ButtonPushedFcn = createCallbackFcn(app, @meta_data, true);
            app.generate_meta_dataButton.Text = 'generate_meta_data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_journal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end